classdef ConfigCSVGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Config                          matlab.ui.Figure
        CompileButton                   matlab.ui.control.Button
        DefaultButton                   matlab.ui.control.Button
        RequiredPanel                   matlab.ui.container.Panel
        create_psthDropDownLabel        matlab.ui.control.Label
        create_psthDropDown             matlab.ui.control.DropDown
        create_mntsDropDownLabel        matlab.ui.control.Label
        create_mntsDropDown             matlab.ui.control.DropDown
        parse_filesDropDownLabel        matlab.ui.control.Label
        parse_filesDropDown             matlab.ui.control.DropDown
        label_channelsDropDownLabel     matlab.ui.control.Label
        label_channelsDropDown          matlab.ui.control.DropDown
        rf_analysisDropDownLabel        matlab.ui.control.Label
        rf_analysisDropDown             matlab.ui.control.DropDown
        make_psth_graphsDropDownLabel   matlab.ui.control.Label
        make_psth_graphsDropDown        matlab.ui.control.DropDown
        nv_analysisDropDownLabel        matlab.ui.control.Label
        nv_analysisDropDown             matlab.ui.control.DropDown
        info_analysisDropDownLabel      matlab.ui.control.Label
        info_analysisDropDown           matlab.ui.control.DropDown
        psth_classifyDropDownLabel      matlab.ui.control.Label
        psth_classifyDropDown           matlab.ui.control.DropDown
        pc_analysisDropDownLabel        matlab.ui.control.Label
        pc_analysisDropDown             matlab.ui.control.DropDown
        ic_analysisDropDownLabel        matlab.ui.control.Label
        ic_analysisDropDown             matlab.ui.control.DropDown
        bin_sizeEditFieldLabel          matlab.ui.control.Label
        bin_sizeEditField               matlab.ui.control.NumericEditField
        pre_timeEditFieldLabel          matlab.ui.control.Label
        pre_timeEditField               matlab.ui.control.NumericEditField
        post_timeEditFieldLabel         matlab.ui.control.Label
        post_timeEditField              matlab.ui.control.NumericEditField
        pre_startEditFieldLabel         matlab.ui.control.Label
        pre_startEditField              matlab.ui.control.NumericEditField
        pre_endEditFieldLabel           matlab.ui.control.Label
        pre_endEditField                matlab.ui.control.NumericEditField
        post_startEditFieldLabel        matlab.ui.control.Label
        post_startEditField             matlab.ui.control.NumericEditField
        post_endEditFieldLabel          matlab.ui.control.Label
        post_endEditField               matlab.ui.control.NumericEditField
        ParserPanel                     matlab.ui.container.Panel
        total_trialsEditFieldLabel      matlab.ui.control.Label
        total_trialsEditField           matlab.ui.control.NumericEditField
        trial_lower_boundEditFieldLabel  matlab.ui.control.Label
        trial_lower_boundEditField      matlab.ui.control.NumericEditField
        total_eventsEditFieldLabel      matlab.ui.control.Label
        total_eventsEditField           matlab.ui.control.NumericEditField
        is_non_strobed_and_strobedDropDown  matlab.ui.control.DropDown
        is_non_strobed_and_strobedDropDownLabel  matlab.ui.control.Label
        event_mapEditFieldLabel         matlab.ui.control.Label
        event_mapEditField              matlab.ui.control.EditField
        PSTHorMNTSPanel                 matlab.ui.container.Panel
        trial_rangeEditFieldLabel       matlab.ui.control.Label
        trial_rangeEditField            matlab.ui.control.EditField
        wanted_eventsEditFieldLabel     matlab.ui.control.Label
        wanted_eventsEditField          matlab.ui.control.EditField
        convert_mnts_psthDropDownLabel  matlab.ui.control.Label
        convert_mnts_psthDropDown       matlab.ui.control.DropDown
        update_psth_windowsDropDownLabel  matlab.ui.control.Label
        update_psth_windowsDropDown     matlab.ui.control.DropDown
        trial_lower_boundEditField_2Label  matlab.ui.control.Label
        trial_lower_boundEditField_2    matlab.ui.control.NumericEditField
        ReceptiveFieldAnalysisPanel     matlab.ui.container.Panel
        spanEditFieldLabel              matlab.ui.control.Label
        spanEditField                   matlab.ui.control.NumericEditField
        threshold_scaleEditFieldLabel   matlab.ui.control.Label
        threshold_scaleEditField        matlab.ui.control.NumericEditField
        sig_binsEditFieldLabel          matlab.ui.control.Label
        sig_binsEditField               matlab.ui.control.NumericEditField
        sig_checkEditFieldLabel         matlab.ui.control.Label
        sig_checkEditField              matlab.ui.control.NumericEditField
        PSTHGraphingPanel               matlab.ui.container.Panel
        make_region_subplotDropDownLabel  matlab.ui.control.Label
        make_region_subplotDropDown     matlab.ui.control.DropDown
        sub_columnsEditFieldLabel       matlab.ui.control.Label
        sub_columnsEditField            matlab.ui.control.NumericEditField
        sub_rowsEditFieldLabel          matlab.ui.control.Label
        sub_rowsEditField               matlab.ui.control.NumericEditField
        NormalizedVarianceAnalysisPanel  matlab.ui.container.Panel
        epsilonEditFieldLabel           matlab.ui.control.Label
        epsilonEditField                matlab.ui.control.NumericEditField
        norm_var_scalingEditFieldLabel  matlab.ui.control.Label
        norm_var_scalingEditField       matlab.ui.control.NumericEditField
        separate_eventsDropDownLabel    matlab.ui.control.Label
        separate_eventsDropDown         matlab.ui.control.DropDown
        PSTHClassifierPanel             matlab.ui.container.Panel
        bootstrap_classifierDropDownLabel  matlab.ui.control.Label
        bootstrap_classifierDropDown    matlab.ui.control.DropDown
        boot_iterationsEditFieldLabel   matlab.ui.control.Label
        boot_iterationsEditField        matlab.ui.control.NumericEditField
        PCAPanel                        matlab.ui.container.Panel
        feature_filterDropDownLabel     matlab.ui.control.Label
        feature_filterDropDown          matlab.ui.control.DropDown
        feature_valueEditFieldLabel     matlab.ui.control.Label
        feature_valueEditField          matlab.ui.control.NumericEditField
        ICAPanel                        matlab.ui.container.Panel
        ic_pcEditFieldLabel             matlab.ui.control.Label
        ic_pcEditField                  matlab.ui.control.NumericEditField
        extendedEditFieldLabel          matlab.ui.control.Label
        extendedEditField               matlab.ui.control.NumericEditField
        spheringDropDownLabel           matlab.ui.control.Label
        spheringDropDown                matlab.ui.control.DropDown
        annealEditFieldLabel            matlab.ui.control.Label
        annealEditField                 matlab.ui.control.EditField
        anneal_degEditFieldLabel        matlab.ui.control.Label
        anneal_degEditField             matlab.ui.control.NumericEditField
        stopEditFieldLabel              matlab.ui.control.Label
        stopEditField                   matlab.ui.control.EditField
        max_stepsEditFieldLabel         matlab.ui.control.Label
        max_stepsEditField              matlab.ui.control.NumericEditField
        biasDropDownLabel               matlab.ui.control.Label
        biasDropDown                    matlab.ui.control.DropDown
        momentumEditFieldLabel          matlab.ui.control.Label
        momentumEditField               matlab.ui.control.NumericEditField
        rnd_resetDropDown               matlab.ui.control.DropDown
        rnd_resetDropDownLabel          matlab.ui.control.Label
        verboseEditFieldLabel           matlab.ui.control.Label
        verboseEditField                matlab.ui.control.EditField
        FormatDropDownLabel             matlab.ui.control.Label
        FormatDropDown                  matlab.ui.control.DropDown
        UploadButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Used to indicate which variables are then required or not
        is_parser_file_true = 'true';
        is_format_psth_true = 'true';
        is_create_psth_true = 'true';
        is_format_mnts_true = 'false';
        is_create_mnts_true = 'false';
        is_pc_analysis_true = 'true';
        is_ic_analysis_true = 'true';
        is_rf_analysis_true = 'true';
        is_make_psth_graphs_true = 'true';
        is_nv_analysis_true = 'true';
        is_psth_classify_true = 'true';
    end
    
    methods (Access = private)
        
        %%%%%%%%%change values to their appropriate default values%%%%%%%%%%
        
        % This callback is for the default button
        function mycallback(app,src,event)
            if(strcmp(event.SelectedOption,'Cancel'))
            elseif (strcmp(event.SelectedOption,'OK'))
                defaultValues(app);    
                checkButtons(app);
            end
        end
        
        % Checks to see whether or not a change should be made for required variables
        function checkButtons(app)
            
            if(strcmp(app.is_format_mnts_true,'true'))
                app.convert_mnts_psthDropDown.Enable = 'on';
                
                app.pc_analysisDropDown.Enable = 'on';
                
                app.ic_analysisDropDown.Enable = 'on';
                
                app.create_mntsDropDown.Enable = 'on';
            else
                app.convert_mnts_psthDropDown.Enable = 'off';
                
                app.pc_analysisDropDown.Enable = 'off';
                
                app.ic_analysisDropDown.Enable = 'off';
                
                app.create_mntsDropDown.Enable = 'off';
            end
            
            if(strcmp(app.is_format_psth_true,'true'))
                app.create_psthDropDown.Enable = 'on';
                
                app.update_psth_windowsDropDown.Enable = 'on';
            else
                app.create_psthDropDown.Enable = 'off';
                
                app.update_psth_windowsDropDown.Enable = 'off';
            end
            
            if(strcmp(app.is_parser_file_true,'true'))
                app.total_trialsEditField.Enable = 'on';
                app.total_trialsEditField.Editable = 'on';
                
                app.trial_lower_boundEditField.Enable = 'on';
                app.trial_lower_boundEditField.Editable = 'on';
                
                app.total_eventsEditField.Enable = 'on';
                app.total_eventsEditField.Editable = 'on';
                
                app.is_non_strobed_and_strobedDropDown.Enable = 'on';
                
                app.event_mapEditField.Enable = 'on';
                app.event_mapEditField.Editable = 'on';
            else
                app.total_trialsEditField.Enable = 'off';
                app.total_trialsEditField.Editable = 'off';
                
                app.trial_lower_boundEditField.Enable = 'off';
                app.trial_lower_boundEditField.Editable = 'off';

                app.total_eventsEditField.Enable = 'off';
                app.total_eventsEditField.Editable = 'off';
                
                app.is_non_strobed_and_strobedDropDown.Enable = 'off';
                
                app.event_mapEditField.Enable = 'off';
                app.event_mapEditField.Editable = 'off';
            end
            
            if(strcmp(app.is_create_psth_true,'true') && strcmp(app.is_format_psth_true,'true'))
                app.trial_lower_boundEditField_2.Enable = 'on';
                app.trial_lower_boundEditField_2.Editable = 'on';
                
                app.trial_rangeEditField.Enable = 'on';
                app.trial_rangeEditField.Editable = 'on';
                
                app.wanted_eventsEditField.Enable = 'on';
                app.wanted_eventsEditField.Editable = 'on';
            else
                if((strcmp(app.is_create_mnts_true,'false') || strcmp(app.is_format_mnts_true,'false')))
                    app.trial_lower_boundEditField_2.Enable = 'off';
                    app.trial_lower_boundEditField_2.Editable = 'off';
                    
                    app.trial_rangeEditField.Enable = 'off';
                    app.trial_rangeEditField.Editable = 'off';
                
                    app.wanted_eventsEditField.Enable = 'off';
                    app.wanted_eventsEditField.Editable = 'off';
                end
            end
            
            if(strcmp(app.is_create_mnts_true,'true') && strcmp(app.is_format_mnts_true,'true'))
                app.trial_lower_boundEditField_2.Enable = 'on';
                app.trial_lower_boundEditField_2.Editable = 'on';
                
                app.trial_rangeEditField.Enable = 'on';
                app.trial_rangeEditField.Editable = 'on';
                
                app.wanted_eventsEditField.Enable = 'on';
                app.wanted_eventsEditField.Editable = 'on';                
            else
                if((strcmp(app.is_create_psth_true,'false') || strcmp(app.is_format_psth_true,'false')))
                    app.trial_lower_boundEditField_2.Enable = 'off';
                    app.trial_lower_boundEditField_2.Editable = 'off';
                    
                    app.trial_rangeEditField.Enable = 'off';
                    app.trial_rangeEditField.Editable = 'off';
                
                    app.wanted_eventsEditField.Enable = 'off';
                    app.wanted_eventsEditField.Editable = 'off';
                end
            end
            
            if(strcmp(app.is_pc_analysis_true,'true') && strcmp(app.is_format_mnts_true,'true'))
                app.feature_filterDropDown.Enable = 'on';
                
                app.feature_valueEditField.Enable = 'on';
                app.feature_valueEditField.Editable = 'on';
            else
                app.feature_filterDropDown.Enable = 'off';
                
                app.feature_valueEditField.Enable = 'off';
                app.feature_valueEditField.Editable = 'off';
            end
            
            if(strcmp(app.is_ic_analysis_true,'true') && strcmp(app.is_format_mnts_true,'true'))
                app.ic_pcEditField.Enable = 'on';
                app.ic_pcEditField.Editable = 'on';
                
                app.extendedEditField.Enable = 'on';
                app.extendedEditField.Editable = 'on';
                
                app.spheringDropDown.Enable = 'on';
                
                app.annealEditField.Enable = 'on';
                app.annealEditField.Editable = 'on';
                
                app.anneal_degEditField.Enable = 'on';
                app.anneal_degEditField.Editable = 'on';
                
                app.stopEditField.Enable = 'on';
                app.stopEditField.Editable = 'on';
                
                app.max_stepsEditField.Enable = 'on';
                app.max_stepsEditField.Editable = 'on';
                
                app.biasDropDown.Enable = 'on';
                
                app.momentumEditField.Enable = 'on';
                app.momentumEditField.Editable = 'on';
                
                app.rnd_resetDropDown.Enable = 'on';
                
                app.verboseEditField.Enable = 'on';
                app.verboseEditField.Editable = 'on';
            else
                app.ic_pcEditField.Enable = 'off';
                app.ic_pcEditField.Editable = 'off';
                
                app.extendedEditField.Enable = 'off';
                app.extendedEditField.Editable = 'off';
                
                app.spheringDropDown.Enable = 'off';
                
                app.annealEditField.Enable = 'off';
                app.annealEditField.Editable = 'off';
                
                app.anneal_degEditField.Enable = 'off';
                app.anneal_degEditField.Editable = 'off';
                
                app.stopEditField.Enable = 'off';
                app.stopEditField.Editable = 'off';
                
                app.max_stepsEditField.Enable = 'off';
                app.max_stepsEditField.Editable = 'off';
                
                app.biasDropDown.Enable = 'off';
                
                app.momentumEditField.Enable = 'off';
                app.momentumEditField.Editable = 'off';
                
                app.rnd_resetDropDown.Enable = 'off';
                
                app.verboseEditField.Enable = 'off';
                app.verboseEditField.Editable = 'off';
            end
            
            if(strcmp(app.is_rf_analysis_true,'true'))
                app.spanEditField.Enable = 'on';
                app.spanEditField.Editable = 'on';
                
                app.threshold_scaleEditField.Enable = 'on';
                app.threshold_scaleEditField.Editable = 'on';
                
                app.sig_binsEditField.Enable = 'on';
                app.sig_binsEditField.Editable = 'on';
                
                app.sig_checkEditField.Enable = 'on';
                app.sig_checkEditField.Editable = 'on';
            else
                app.spanEditField.Enable = 'off';
                app.spanEditField.Editable = 'off';
                
                app.threshold_scaleEditField.Enable = 'off';
                app.threshold_scaleEditField.Editable = 'off';
                
                app.sig_binsEditField.Enable = 'off';
                app.sig_binsEditField.Editable = 'off';
                
                app.sig_checkEditField.Enable = 'off';
                app.sig_checkEditField.Editable = 'off';
            end
            
            if(strcmp(app.is_make_psth_graphs_true,'true'))
                app.make_region_subplotDropDown.Enable = 'on';
                
                app.sub_columnsEditField.Enable = 'on';
                app.sub_columnsEditField.Editable = 'on';  
                
                app.sub_rowsEditField.Enable = 'on';
                app.sub_rowsEditField.Editable = 'on';
            else
                app.make_region_subplotDropDown.Enable = 'off';
                
                app.sub_columnsEditField.Enable = 'off';
                app.sub_columnsEditField.Editable = 'off';  
                
                app.sub_rowsEditField.Enable = 'off';
                app.sub_rowsEditField.Editable = 'off';
            end
            
            if(strcmp(app.is_nv_analysis_true,'true'))
                app.epsilonEditField.Enable = 'on';
                app.epsilonEditField.Editable = 'on';
                
                app.norm_var_scalingEditField.Enable = 'on';
                app.norm_var_scalingEditField.Editable = 'on';
                
                app.separate_eventsDropDown.Enable = 'on';
            else
                app.epsilonEditField.Enable = 'off';
                app.epsilonEditField.Editable = 'off';
                
                app.norm_var_scalingEditField.Enable = 'off';
                app.norm_var_scalingEditField.Editable = 'off';
                
                app.separate_eventsDropDown.Enable = 'off';
            end
            
            if(strcmp(app.is_psth_classify_true,'true'))
                app.bootstrap_classifierDropDown.Enable = 'on';
                
                app.boot_iterationsEditField.Enable = 'on';
                app.boot_iterationsEditField.Editable = 'on';
            else
                app.bootstrap_classifierDropDown.Enable = 'off';
                
                app.boot_iterationsEditField.Enable = 'off';
                app.boot_iterationsEditField.Editable = 'off';
            end
            
        end
        
        function defaultValues(app)
            app.parse_filesDropDown.Value = 'true';
            app.label_channelsDropDown.Value = 'true';
            app.create_psthDropDown.Value = 'true';
            app.rf_analysisDropDown.Value = 'true';
            app.make_psth_graphsDropDown.Value = 'true';
            app.nv_analysisDropDown.Value = 'true';
            app.info_analysisDropDown.Value = 'true';
            app.psth_classifyDropDown.Value = 'true';
            app.create_mntsDropDown.Value = 'true';
            app.pc_analysisDropDown.Value = 'true';
            app.ic_analysisDropDown.Value = 'true';
            app.bin_sizeEditField.Value = 0.005;
            app.pre_timeEditField.Value = 0.2;
            app.post_timeEditField.Value = 0.2;
            app.total_trialsEditField.Value = 100;
            app.trial_lower_boundEditField.Value = 50;
            app.trial_lower_boundEditField_2.Value = 50;
            app.total_eventsEditField.Value = 4;            
            app.is_non_strobed_and_strobedDropDown.Value = 'true'; 
            app.event_mapEditField.Value = '';
            app.trial_rangeEditField.Value = '';
            app.wanted_eventsEditField.Value = '';
            app.spanEditField.Value = 3;
            app.threshold_scaleEditField.Value = 3;
            app.sig_binsEditField.Value = 3;
            app.sig_checkEditField.Value = 1;
            app.make_region_subplotDropDown.Value = 'true';
            app.sub_columnsEditField.Value = 3;
            app.sub_rowsEditField.Value = 2;
            app.epsilonEditField.Value = 0.01;
            app.norm_var_scalingEditField.Value = 0.2;
            app.separate_eventsDropDown.Value = 'true';
            app.bootstrap_classifierDropDown.Value = 'true';
            app.boot_iterationsEditField.Value = 50;
            app.feature_filterDropDown.Value = 'percent_var';
            app.feature_valueEditField.Value = 70;
            app.ic_pcEditField.Value = 0;
            app.extendedEditField.Value = 0;
            app.spheringDropDown.Value = 'on';
            app.annealEditField.Value = 'default';
            app.anneal_degEditField.Value = 70;
            app.stopEditField.Value = 'default';
            app.max_stepsEditField.Value = 512;
            app.biasDropDown.Value = 'on';
            app.momentumEditField.Value = 0;
            app.rnd_resetDropDown.Value = 'false';
            app.verboseEditField.Value = 'off';
            app.pre_startEditField.Value = 0;
            app.pre_endEditField.Value = 0;
            app.post_startEditField.Value = 0;
            app.post_endEditField.Value = 0;
            
            app.is_parser_file_true = 'true';
            app.is_format_psth_true = 'true';
            app.is_create_psth_true = 'true';
            app.is_format_mnts_true = 'false';
            app.is_create_mnts_true = 'true';
            app.is_pc_analysis_true = 'true';
            app.is_ic_analysis_true = 'true';
            app.is_rf_analysis_true = 'true';
            app.is_make_psth_graphs_true = 'true';
            app.is_nv_analysis_true = 'true';
            app.is_psth_classify_true = 'true';
            
            app.FormatDropDown.Value = 'PSTH';
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            defaultValues(app);
            checkButtons(app);
        end

        % Button pushed function: CompileButton
        function CompileButtonPushed(app, event)
               %%% Internal variables %%%
            goBackField = true;
            
            %%% All variable values from UI %%%%
            parse_files = app.parse_filesDropDown.Value;
            label_channels = app.label_channelsDropDown.Value;
            create_psth = app.create_psthDropDown.Value;
            rf_analysis = app.rf_analysisDropDown.Value;
            make_psth_graphs = app.make_psth_graphsDropDown.Value;
            nv_analysis = app.nv_analysisDropDown.Value;
            info_analysis = app.info_analysisDropDown.Value;
            psth_classify = app.psth_classifyDropDown.Value;
            create_mnts = app.create_mntsDropDown.Value;
            pc_analysis = app.pc_analysisDropDown.Value;
            ic_analysis = app.ic_analysisDropDown.Value;
            bin_size = app.bin_sizeEditField.Value;
            pre_time = app.pre_timeEditField.Value;
            post_time = app.post_timeEditField.Value;
            ignore_animal = 'holder';
            total_trials = app.total_trialsEditField.Value;
            trial_lower_bound = app.trial_lower_boundEditField.Value;
            total_events = app.total_eventsEditField.Value;
            is_non_strobed_and_strobed = app.is_non_strobed_and_strobedDropDown.Value;
            event_map = app.event_mapEditField.Value;
            trial_range = app.trial_rangeEditField.Value;
            wanted_events = app.wanted_eventsEditField.Value;
            span = app.spanEditField.Value;
            threshold_scale = app.threshold_scaleEditField.Value;
            sig_bins = app.sig_binsEditField.Value;
            sig_check = app.sig_checkEditField.Value;
            make_region_subplot = app.make_region_subplotDropDown.Value;
            sub_columns = app.sub_columnsEditField.Value;
            sub_rows = app.sub_rowsEditField.Value;
            epsilon = app.epsilonEditField.Value;
            norm_var_scaling = app.norm_var_scalingEditField.Value;
            separate_events = app.separate_eventsDropDown.Value;
            bootstrap_classifier = app.bootstrap_classifierDropDown.Value;
            boot_iterations = app.boot_iterationsEditField.Value;
            feature_filter = app.feature_filterDropDown.Value;
            feature_value = app.feature_valueEditField.Value;
            ic_pc = app.ic_pcEditField.Value;
            extended = app.extendedEditField.Value;
            sphering = app.spheringDropDown.Value;
            anneal = app.annealEditField.Value;
            anneal_deg = app.anneal_degEditField.Value;
            stop = app.stopEditField.Value;
            max_steps = app.max_stepsEditField.Value;
            bias = app.biasDropDown.Value;
            momentum = app.momentumEditField.Value;
            rnd_reset = app.rnd_resetDropDown.Value;
            verbose = app.verboseEditField.Value;
            formatButton = app.FormatDropDown.Value;
            pre_start = app.pre_startEditField.Value;
            pre_end = app.pre_endEditField.Value;
            post_start = app.post_startEditField.Value;
            post_end = app.post_endEditField.Value;
            convert_mnts_psth = app.convert_mnts_psthDropDown.Value;
            update_psth_windows = app.update_psth_windowsDropDown.Value;            
            
            %this is for fields with array of numerical values
            if event_map ~= ""
                event_map = erase(event_map,{'[',']'});
                event_map = strrep(event_map,' ',',');
            end
            
            if trial_range ~= ""
                trial_range = erase(trial_range,{'[',']'});
                trial_range = strrep(trial_range,' ',',');
            end
            
            if wanted_events ~= ""
                wanted_events = erase(wanted_events,{'[',']'});
                wanted_events = strrep(wanted_events,' ',',');
            end
            
            % Put variable names in here
            variables = ["key" "parse_files" "label_channels"];
            values = ["value",string(parse_files),label_channels];
            
            if strcmp(app.create_psthDropDown.Enable,'on')
                variables = [variables "create_psth"];
                values = [values,create_psth];
            end
            
            variables = [variables "rf_analysis" "make_psth_graphs" "nv_analysis" "info_analysis" "psth_classify"];
            values = [values,rf_analysis,make_psth_graphs,nv_analysis,info_analysis,psth_classify];
                
            if strcmp(app.create_mntsDropDown.Enable,'on')
                variables = [variables "create_mnts"];
                values = [values,create_mnts];
            end
            
            % Put variable values in here
            
            if strcmp(app.pc_analysisDropDown.Enable,'on')
                variables = [variables "pc_analysis"];
                values = [values,pc_analysis];
            end
            
            if strcmp(app.ic_analysisDropDown.Enable,'on')
                variables = [variables "ic_analysis"];
                values = [values,ic_analysis];
            end
            
            variables = [variables "bin_size" "pre_time" "post_time" "pre_start" "pre_end" "post_start" "post_end" "ignore_animal"];
            values = [values,bin_size,pre_time,post_time,pre_start,pre_end,post_start,post_end,ignore_animal];
            
            % These are for variables that could be not required
           
            if strcmp(app.total_trialsEditField.Enable,'on')
                variables = [variables "total_trials"];
                values = [values,total_trials];
            end
            
            if (strcmp(app.trial_lower_boundEditField.Enable,'on') || strcmp(app.trial_lower_boundEditField_2.Enable,'on'))
                variables = [variables "trial_lower_bound"];
                values = [values,trial_lower_bound];
            end
            
            if strcmp(app.total_eventsEditField.Enable,'on')
                variables = [variables "total_events"];
                values = [values,total_events];
            end
            
            if strcmp(app.is_non_strobed_and_strobedDropDown.Enable,'on')
                variables = [variables "is_non_strobed_and_strobed"];
                values = [values,is_non_strobed_and_strobed];
            end
            
            if strcmp(app.event_mapEditField.Enable,'on')
                variables = [variables "event_map"];
                values = [values,event_map];
            end
            
            if strcmp(app.trial_rangeEditField.Enable,'on')
                variables = [variables "trial_range"];
                values = [values,trial_range];
            end
            
            if strcmp(app.wanted_eventsEditField.Enable,'on')
                variables = [variables "wanted_events"];
                values = [values,wanted_events];
            end
            
            if strcmp(app.convert_mnts_psthDropDown.Enable,'on')
                variables = [variables "convert_mnts_psth"];
                values = [values,convert_mnts_psth];
            end
            
            if strcmp(app.update_psth_windowsDropDown.Enable,'on')
                variables = [variables "update_psth_windows"];
                values = [values,update_psth_windows];
            end
            
            if strcmp(app.spanEditField.Enable,'on')
                variables = [variables "span"];
                values = [values,span];
            end
            
            if strcmp(app.threshold_scaleEditField.Enable,'on')
                variables = [variables "threshold_scale"];
                values = [values,threshold_scale];
            end
            
            if strcmp(app.sig_binsEditField.Enable,'on')
                variables = [variables "sig_bins"];
                values = [values,sig_bins];
            end
            
            if strcmp(app.sig_checkEditField.Enable,'on')
                variables = [variables "sig_check"];
                values = [values,sig_check];
            end
            
            if strcmp(app.make_region_subplotDropDown.Enable,'on')
                variables = [variables "make_region_subplot"];
                values = [values,make_region_subplot];
            end
            
            if strcmp(app.sub_columnsEditField.Enable,'on')
                variables = [variables "sub_columns"];
                values = [values,sub_columns];
            end
            
            if strcmp(app.sub_rowsEditField.Enable,'on')
                variables = [variables "sub_rows"];
                values = [values,sub_rows];
            end

            if strcmp(app.epsilonEditField.Enable,'on')
                variables = [variables "epsilon"];
                values = [values,epsilon];
            end

            if strcmp(app.norm_var_scalingEditField.Enable,'on')
                variables = [variables "norm_var_scaling"];
                values = [values,norm_var_scaling];
            end

            if strcmp(app.separate_eventsDropDown.Enable,'on')
                variables = [variables "separate_events"];
                values = [values,separate_events];
            end

            if strcmp(app.bootstrap_classifierDropDown.Enable,'on')
                variables = [variables "bootstrap_classifier"];
                values = [values,bootstrap_classifier];
            end

            if strcmp(app.boot_iterationsEditField.Enable,'on')
                variables = [variables "boot_iterations"];
                values = [values,boot_iterations];
            end

            if strcmp(app.feature_filterDropDown.Enable,'on')
                variables = [variables "feature_filter"];
                values = [values,feature_filter];
            end
            
            if strcmp(app.feature_valueEditField.Enable,'on')
                variables = [variables "feature_value"];
                values = [values,feature_value];
            end
            
            if strcmp(app.ic_pcEditField.Enable,'on')
                variables = [variables "ic_pc"];
                values = [values,ic_pc];
            end
            
            if strcmp(app.extendedEditField.Enable,'on')
                variables = [variables "extended"];
                values = [values,extended];
            end
            
            if strcmp(app.spheringDropDown.Enable,'on')
                variables = [variables "sphering"];
                values = [values,sphering];
            end
            
            if strcmp(app.annealEditField.Enable,'on')
                variables = [variables "anneal"];
                values = [values,anneal];
            end
            
            if strcmp(app.anneal_degEditField.Enable,'on')
                variables = [variables "anneal_deg"];
                values = [values,anneal_deg];
            end
            
            if strcmp(app.stopEditField.Enable,'on')
                variables = [variables "stop"];
                values = [values,stop];
            end
            
            if strcmp(app.max_stepsEditField.Enable,'on')
                variables = [variables "max_steps"];
                values = [values,max_steps];
            end
            
            if strcmp(app.biasDropDown.Enable,'on')
                variables = [variables "bias"];
                values = [values,bias];
            end
            
            if strcmp(app.momentumEditField.Enable,'on')
                variables = [variables "momentum"];
                values = [values,momentum];
            end
            
            if strcmp(app.rnd_resetDropDown.Enable,'on')
                variables = [variables "rnd_reset"];
                values = [values,rnd_reset];
            end
            
            if strcmp(app.verboseEditField.Enable,'on')
                variables = [variables,"verbose"];
                values = [values,verbose];
            end
            
            % converts array so it can be processed as CSV
            T = array2table(values','RowNames',variables');
            
            % gets the file names
            original_path = uigetdir(pwd);
            animal_list = dir(string(original_path));
            animal_names = {animal_list([animal_list.isdir] == 1 & ~contains({animal_list.name}, '.')).name};
            animal_array_values = [];
            ignore_animals = [];
            ignore_sessions = {};
            
            % creates new popup for choosing which animals to ignore
            ignoreFig = uifigure('Position',[334 328 300 300],'Scrollable','on');
            
            uibutton(ignoreFig,'push',...
               'Position',[20, 10, 100, 22],...
               'Text', 'Next',...
               'FontColor',[1,1,1],...
               'BackgroundColor',[0.39,0.73,0.95],...
               'ButtonPushedFcn', @(btn,event) getAnimalsButtonPushed());
           
            uibutton(ignoreFig,'push',...
               'Position',[280-100, 10, 100, 22],...
               'Text', 'Cancel',...
               'ButtonPushedFcn', @(btn,event) goBackIgnore());
           
            newArray = [];
            recordingSessions = {};
            newReduce = 30;
             for c = length(animal_names):-1:1
                %goes through each file name
                animal_name = char(animal_names(c));
                %splitting animal name into components
                session_num = regexp(animal_name,'\d*','Match');
                session_name = regexp(animal_name,'.*[^\d]','Match');
                labelsFileName = strcat(session_name,'.',session_num,'.labels');
                pathOfFile = strcat(original_path,'/',animal_name,'/');
                nameForCSV = strcat(string(pathOfFile),string(labelsFileName),'.csv');
                oldTable = readtable(string(nameForCSV));
                recordingSessionArray = oldTable.recording_session;
                recordingSessionArray = unique(recordingSessionArray);
                recordingSession = [];
                for j = length(recordingSessionArray):-1:1
                    newReduce = newReduce + 20;
                    recordingSessionNumber = int2str(recordingSessionArray(j));
                    recordingSession = [recordingSession, uicheckbox(ignoreFig, 'Text',recordingSessionNumber,...
                  'Value', 0,...
                  'Position',[140 (newReduce) 100 20])];
                end
                newReduce = newReduce + 20;
                newArray = [newArray, uicheckbox(ignoreFig, 'Text',animal_name,...
                  'Value', 0,...
                  'Position',[120 (newReduce) 100 20],...
                  'ValueChangedFcn',@(cbx,event) cBoxChanged(cbx,c))];
                recordingSessions{c} = recordingSession;
             end
            
            uilabel(ignoreFig,'Text','What animals and recording do you want to ignore?','Position',[12 (newReduce + 20) 290 32]);
           
            % button to be pressed after selecting which files to save config to
            function getAnimalsButtonPushed()
                for index = 1:length(newArray)
                    ignore_animals = [ignore_animals, newArray(index).Value];
                    ignore_session = [];
                    newRecordingSession = recordingSessions{1,index};
                    for innerIndex = length(newRecordingSession):-1:1
                        if (newRecordingSession(innerIndex).Value == 1)
                            ignore_session = [ignore_session,newRecordingSession(innerIndex).Text];
                        end
                    end
                    ignore_sessions{index} = ignore_session;
                end
                ignoreFig.delete;
                goBackField = false;
            end
            
            function goBackIgnore()
                ignoreFig.delete;
            end
            
            function cBoxChanged(cbx,c)
                val = cbx.Value;
                if val
                    newRecordingSession = recordingSessions{1,c};
                    for i = 1:length(newRecordingSession)
                        newRecordingSession(i).Value = 1;
                    end
                end
            end
            
            function goBackDuplicate()
                fig.delete;
            end
            
            function plotButtonPushed()
                    for index = 1:length(newArray)
                        animal_array_values = [animal_array_values, newArray(index).Value];
                    end
                    fig.delete;
                    goBackField = false;
            end
            
            waitfor(ignoreFig);
            
            if (goBackField == false)
                goBackField = true;
                % creates new popup for choosing the file name
                fig = uifigure('Position',[334 328 300 300],'Scrollable','on');
                
                uibutton(fig,'push',...
                'Position',[20, 10, 100, 22],...
                'Text', 'Compile',...
                'FontColor',[1,1,1],...
                'BackgroundColor',[0.39,0.73,0.95],...
                'ButtonPushedFcn', @(btn,event) plotButtonPushed());
           
                uibutton(fig,'push',...
                'Position',[280-100, 10, 100, 22],...
                'Text', 'Cancel',...
                'ButtonPushedFcn', @(btn,event) goBackDuplicate());
            
                newArray = [];
                for c = 1:length(animal_names)
                    animal_name = char(animal_names(c));
                    newArray = [newArray, uicheckbox(fig, 'Text',animal_name,...
                    'Value', 0,...
                    'Position',[120 (50 + (length(animal_names) - c)*20) 100 20])];
                end
            
                uilabel(fig,'Text','What files to copy to?','Position',[90 (50 + 10 + length(animal_names)*20) 220 32]);
            
           
                % button to be pressed after selecting which files to save config to

            
                waitfor(fig);
            
                if (goBackField ~= true)
                    % generation of the config files
                    for c = 1:length(animal_names)
                        if(animal_array_values(c) == 1)
                            animal_name = char(animal_names(c));
                            session_num = regexp(animal_name,'\d*','Match');
                            session_name = regexp(animal_name,'.*[^\d]','Match');
                            if (strcmp(formatButton,'MNTS'))
                                configFileName = strcat(session_name,'_',session_num,'_mnts_config');
                            else
                                configFileName = strcat(session_name,'_',session_num,'_psth_config');
                            end 
                            pathOfFile = strcat(original_path,'/',animal_name,'/');
                            nameForCSV = strcat(string(pathOfFile),string(configFileName),'.csv');
                            writetable(T,string(nameForCSV),'WriteRowNames',true,'WriteVariableNames',0);
                        end
                    end
                    %for ignoring animals
                    for c = 1:length(animal_names)
                        animal_name = char(animal_names(c));
                        session_num = regexp(animal_name,'\d*','Match');
                        session_name = regexp(animal_name,'.*[^\d]','Match');
                        if (strcmp(formatButton,'MNTS'))
                            configFileName = strcat(session_name,'_',session_num,'_mnts_config');
                        else
                            configFileName = strcat(session_name,'_',session_num,'_psth_config');
                        end 
                        pathOfFile = strcat(original_path,'/',animal_name,'/');
                        nameForCSV = strcat(string(pathOfFile),string(configFileName),'.csv');
                        %if there is no file that exists, then cannot modify it
                        if isfile(string(nameForCSV))
                           oldTable = readtable(string(nameForCSV));
                           if(ignore_animals(c) == 1)
                                oldTable.value(find(strcmp(oldTable.key,'ignore_animal'))) = cellstr('true');
                                key = "ignore_sessions";
                                if(isempty(find(strcmp(oldTable.key,key))))
                                    newTable = table(key,'');
                                    oldTable = [oldTable;newTable];
                                else
                                    oldTable.value(find(strcmp(oldTable.key,key))) = '';
                                end
                           else
                                oldTable.value(find(strcmp(oldTable.key,'ignore_animal'))) = cellstr('false');
                                ignore_session = ignore_sessions{1,c};
                                ignore_session_variable = "";
                                for index = 1:length(ignore_session)
                                    ignore_session_variable = strcat(ignore_session_variable,ignore_session(index)," ");
                                end
                                value = strip(ignore_session_variable);
                                key = "ignore_sessions";
                                if(isempty(find(strcmp(oldTable.key,key))))
                                    newTable = table(key,value);
                                    oldTable = [oldTable;newTable];
                                else
                                    oldTable.value(find(strcmp(oldTable.key,key))) = cellstr(value);
                                end
                           end 
                           writetable(oldTable,string(nameForCSV),'WriteRowNames',true,'WriteVariableNames',1);
                        end
                    end
                    f = msgbox('Generated CSV File');
                    waitfor(f);
                    app.delete;
                end
            end
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            uiconfirm(app.Config,'Restore default settings?','Confirm Close',...
                        'CloseFcn',@(src,event)mycallback(app,src,event));
        end

        % Value changed function: parse_filesDropDown
        function parse_filesDropDownValueChanged(app, event)
            value = app.parse_filesDropDown.Value;
            if (value == "true")
                app.is_parser_file_true = 'true';
            else
                app.is_parser_file_true = 'false';
            end
            checkButtons(app);
        end

        % Value changed function: pc_analysisDropDown
        function pc_analysisDropDownValueChanged(app, event)
            value = app.pc_analysisDropDown.Value;
            if (value == "true")
                app.is_pc_analysis_true = 'true';
            else 
                app.is_pc_analysis_true = 'false';
            end
            checkButtons(app);
        end

        % Value changed function: ic_analysisDropDown
        function ic_analysisDropDownValueChanged(app, event)
            value = app.ic_analysisDropDown.Value;
            if (value == "true")
                app.is_ic_analysis_true = 'true';
            else 
                app.is_ic_analysis_true = 'false';
            end
            checkButtons(app);
        end

        % Value changed function: rf_analysisDropDown
        function rf_analysisDropDownValueChanged(app, event)
            value = app.rf_analysisDropDown.Value;
            if (value == "true")
                app.is_rf_analysis_true = 'true';
            else 
                app.is_rf_analysis_true = 'false';
            end
            checkButtons(app);
        end

        % Value changed function: make_psth_graphsDropDown
        function make_psth_graphsDropDownValueChanged(app, event)
            value = app.make_psth_graphsDropDown.Value;
            if (value == "true")
                app.is_make_psth_graphs_true = 'true';
            else 
                app.is_make_psth_graphs_true = 'false';
            end
            checkButtons(app);
        end

        % Value changed function: nv_analysisDropDown
        function nv_analysisDropDownValueChanged(app, event)
            value = app.nv_analysisDropDown.Value;
            if (value == "true")
                app.is_nv_analysis_true = 'true';
            else 
                app.is_nv_analysis_true = 'false';
            end
            checkButtons(app);
        end

        % Value changed function: psth_classifyDropDown
        function psth_classifyDropDownValueChanged(app, event)
            value = app.psth_classifyDropDown.Value;
            if (value == "true")
                app.is_psth_classify_true = 'true';
            else 
                app.is_psth_classify_true = 'false';
            end
            checkButtons(app);
        end

        % Value changed function: FormatDropDown
        function FormatDropDownValueChanged(app, event)
            value = app.FormatDropDown.Value;
            if (value == 'PSTH')
                app.is_format_psth_true = 'true';
                app.is_format_mnts_true = 'false';
            elseif (value == 'MNTS')
                app.is_format_psth_true = 'false';
                app.is_format_mnts_true = 'true';
            end
            checkButtons(app);
        end

        % Value changed function: create_psthDropDown
        function create_psthDropDownValueChanged(app, event)
            value = app.create_psthDropDown.Value;
            if (value == "true")
                app.is_create_psth_true = 'true';
            else 
                app.is_create_psth_true = 'false';
            end
            checkButtons(app);
        end

        % Value changed function: create_mntsDropDown
        function create_mntsDropDownValueChanged(app, event)
            value = app.create_mntsDropDown.Value;
            if (value == "true")
                app.is_create_mnts_true = 'true';
            else 
                app.is_create_mnts_true = 'false';
            end
            checkButtons(app);
        end

        % Value changed function: trial_lower_boundEditField_2
        function trial_lower_boundEditField_2ValueChanged(app, event)
            value = app.trial_lower_boundEditField_2.Value;
            app.trial_lower_boundEditField.Value = value;
        end

        % Value changed function: trial_lower_boundEditField
        function trial_lower_boundEditFieldValueChanged(app, event)
            value = app.trial_lower_boundEditField.Value;
            app.trial_lower_boundEditField_2.Value = value;
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [csvFileName,csvPath] = uigetfile('.csv');
            configTable = readtable([csvPath csvFileName]);
            %if it is a mnts config
            if contains(csvFileName, 'mnts')
                app.FormatDropDown.Value = 'MNTS';
                app.is_format_psth_true = 'false';
                app.is_format_mnts_true = 'true';
            else
                app.FormatDropDown.Value = 'PSTH';
                app.is_format_psth_true = 'true';
                app.is_format_mnts_true = 'false';
            end
            
            if ~isempty(find(strcmp(configTable.key,'create_psth')))
                app.create_psthDropDown.Value = configTable.value(find(strcmp(configTable.key,'create_psth')));
                if strcmp(app.create_psthDropDown.Value,'true')
                    app.is_create_psth_true = 'true';
                else
                    app.is_create_psth_true = 'false';
                end
            end
            
            if ~isempty(find(strcmp(configTable.key,'create_mnts')))
                app.create_mntsDropDown.Value = configTable.value(find(strcmp(configTable.key,'create_mnts')));
                if strcmp(app.create_mntsDropDown.Value,'true')
                    app.is_create_mnts_true = 'true';
                else
                    app.is_create_mnts_true = 'false';
                end
            end
            
            if ~isempty(find(strcmp(configTable.key,'parse_files')))
                app.parse_filesDropDown.Value = configTable.value(find(strcmp(configTable.key,'parse_files')));
                if strcmp(app.parse_filesDropDown.Value,'true')
                    app.is_parser_file_true = 'true';
                else
                    app.is_parser_file_true = 'false';
                end
            end
            
            if ~isempty(find(strcmp(configTable.key,'label_channels')))
                app.label_channelsDropDown.Value = configTable.value(find(strcmp(configTable.key,'label_channels')));
            end
            
            if ~isempty(find(strcmp(configTable.key,'rf_analysis')))
                app.rf_analysisDropDown.Value = configTable.value(find(strcmp(configTable.key,'rf_analysis')));
                if strcmp(app.rf_analysisDropDown.Value,'true')
                    app.is_rf_analysis_true = 'true';
                else
                    app.is_rf_analysis_true = 'false';
                end
            end
            
            if ~isempty(find(strcmp(configTable.key,'make_psth_graphs')))
                app.make_psth_graphsDropDown.Value = configTable.value(find(strcmp(configTable.key,'make_psth_graphs')));
                if strcmp(app.make_psth_graphsDropDown.Value,'true')
                    app.is_make_psth_graphs_true = 'true';
                else
                    app.is_make_psth_graphs_true = 'false';
                end
            end
            
            if ~isempty(find(strcmp(configTable.key,'nv_analysis')))
                app.nv_analysisDropDown.Value = configTable.value(find(strcmp(configTable.key,'nv_analysis')));
                if strcmp(app.nv_analysisDropDown.Value,'true')
                    app.is_nv_analysis_true = 'true';
                else
                    app.is_nv_analysis_true = 'false';
                end
            end
            
            if ~isempty(find(strcmp(configTable.key,'info_analysis')))
                app.info_analysisDropDown.Value = configTable.value(find(strcmp(configTable.key,'info_analysis')));
            end
            
            if ~isempty(find(strcmp(configTable.key,'psth_classify')))
                app.psth_classifyDropDown.Value = configTable.value(find(strcmp(configTable.key,'psth_classify')));
                if strcmp(app.psth_classifyDropDown.Value,'true')
                    app.is_psth_classify_true = 'true';
                else
                    app.is_psth_classify_true = 'false';
                end
            end
            
            if ~isempty(find(strcmp(configTable.key,'pc_analysis')))
                app.pc_analysisDropDown.Value = configTable.value(find(strcmp(configTable.key,'pc_analysis')));
                if strcmp(app.pc_analysisDropDown.Value,'true')
                    app.is_pc_analysis_true = 'true';
                else
                    app.is_pc_analysis_true = 'false';
                end
            end
            
            if ~isempty(find(strcmp(configTable.key,'ic_analysis')))
                app.ic_analysisDropDown.Value = configTable.value(find(strcmp(configTable.key,'ic_analysis')));
                if strcmp(app.ic_analysisDropDown.Value,'true')
                    app.is_ic_analysis_true = 'true';
                else
                    app.is_ic_analysis_true = 'false';
                end
            end
            
            if ~isempty(find(strcmp(configTable.key,'bin_size')))
                app.bin_sizeEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'bin_size'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'pre_time')))
                app.pre_timeEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'pre_time'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'post_time')))
                app.post_timeEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'post_time'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'pre_start')))
                app.pre_startEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'pre_start'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'pre_end')))
                app.pre_endEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'pre_end'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'post_start')))
                app.post_startEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'post_start'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'post_end')))
                app.post_endEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'post_end'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'total_trials')))
                app.total_trialsEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'total_trials'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'trial_lower_bound')))
                app.trial_lower_boundEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'trial_lower_bound'))));
                app.trial_lower_boundEditField_2.Value = str2double(configTable.value(find(strcmp(configTable.key,'trial_lower_bound'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'total_events')))
                app.total_eventsEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'total_events'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'is_non_strobed_and_strobed')))
                app.is_non_strobed_and_strobedDropDown.Value = configTable.value(find(strcmp(configTable.key,'is_non_strobed_and_strobed')));
            end
            
            if ~isempty(find(strcmp(configTable.key,'event_map')))
                if ~isempty(char(configTable.value(find(strcmp(configTable.key,'event_map')))))
                    app.event_mapEditField.Value = ['[' char(configTable.value(find(strcmp(configTable.key,'event_map')))) ']'];
                else
                    app.event_mapEditField.Value = '';
                end
            end
            
            if ~isempty(find(strcmp(configTable.key,'trial_range')))
                if ~isempty(char(configTable.value(find(strcmp(configTable.key,'trial_range')))))
                    app.trial_rangeEditField.Value = ['[' char(configTable.value(find(strcmp(configTable.key,'trial_range')))) ']'];
                else
                    app.trial_rangeEditField.Value = '';
                end
            end
            
            if ~isempty(find(strcmp(configTable.key,'wanted_events')))
                if ~isempty(char(configTable.value(find(strcmp(configTable.key,'wanted_events')))))
                    app.wanted_eventsEditField.Value = ['[' char(configTable.value(find(strcmp(configTable.key,'wanted_events')))) ']'];
                else
                    app.wanted_eventsEditField.Value = '';
                end
            end
            
            if ~isempty(find(strcmp(configTable.key,'convert_mnts_psth')))
                app.convert_mnts_psthDropDown.Value = configTable.value(find(strcmp(configTable.key,'convert_mnts_psth')));
            end
            
            if ~isempty(find(strcmp(configTable.key,'update_psth_windows')))
                app.update_psth_windowsDropDown.Value = configTable.value(find(strcmp(configTable.key,'update_psth_windows')));
            end
            
            if ~isempty(find(strcmp(configTable.key,'span')))
                app.spanEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'span'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'threshold_scale')))
                app.threshold_scaleEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'threshold_scale'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'sig_bins')))
                app.sig_binsEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'sig_bins'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'sig_check')))
                app.sig_checkEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'sig_check'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'make_region_subplot')))
                app.make_region_subplotDropDown.Value = configTable.value(find(strcmp(configTable.key,'make_region_subplot')));
            end
            
            if ~isempty(find(strcmp(configTable.key,'sub_columns')))
                app.sub_columnsEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'sub_columns'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'sub_rows')))
                app.sub_rowsEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'sub_rows'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'epsilon')))
                app.epsilonEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'epsilon'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'norm_var_scaling')))
                app.norm_var_scalingEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'norm_var_scaling'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'separate_events')))
                app.separate_eventsDropDown.Value = configTable.value(find(strcmp(configTable.key,'separate_events')));
            end
            
            if ~isempty(find(strcmp(configTable.key,'bootstrap_classifier')))
                app.bootstrap_classifierDropDown.Value = configTable.value(find(strcmp(configTable.key,'bootstrap_classifier')));
            end
            
            if ~isempty(find(strcmp(configTable.key,'boot_iterations')))
                app.boot_iterationsEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'boot_iterations'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'feature_filter')))
                app.feature_filterDropDown.Value = configTable.value(find(strcmp(configTable.key,'feature_filter')));
            end
            
            if ~isempty(find(strcmp(configTable.key,'feature_value')))
                app.feature_valueEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'feature_value'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'ic_pc')))
                app.ic_pcEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'ic_pc'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'extended')))
                app.extendedEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'extended'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'sphering')))
                app.spheringDropDown.Value = configTable.value(find(strcmp(configTable.key,'sphering')));
            end
            
            if ~isempty(find(strcmp(configTable.key,'anneal')))
                if strcmp(configTable.value(find(strcmp(configTable.key,'anneal'))),'default')
                    app.annealEditField.Value = 'default';
                else
                    app.annealEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'anneal'))));
                end
            end
            
            if ~isempty(find(strcmp(configTable.key,'anneal_deg')))
                app.anneal_degEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'anneal_deg'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'stop')))
                if strcmp(configTable.value(find(strcmp(configTable.key,'stop'))),'default')
                    app.stopEditField.Value = 'default';
                else
                    app.stopEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'stop'))));
                end
            end
            
            if ~isempty(find(strcmp(configTable.key,'max_steps')))
                app.max_stepsEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'max_steps'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'bias')))
                app.biasDropDown.Value = configTable.value(find(strcmp(configTable.key,'bias')));
            end
            
            if ~isempty(find(strcmp(configTable.key,'momentum')))
                app.momentumEditField.Value = str2double(configTable.value(find(strcmp(configTable.key,'momentum'))));
            end
            
            if ~isempty(find(strcmp(configTable.key,'rnd_reset')))
                app.rnd_resetDropDown.Value = configTable.value(find(strcmp(configTable.key,'rnd_reset')));
            end
            
            if ~isempty(find(strcmp(configTable.key,'verbose')))
                app.verboseEditField.Value = char(configTable.value(find(strcmp(configTable.key,'verbose'))));
            end
            
            checkButtons(app);
            disp("Uploaded Config File to UI");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Config = uifigure('Visible', 'off');
            app.Config.Name = 'Config';
            app.CompileButton = uibutton(app.Config, 'push');
            app.CompileButton.ButtonPushedFcn = createCallbackFcn(app, @CompileButtonPushed, true);
            app.CompileButton.Text = 'Compile';
            app.DefaultButton = uibutton(app.Config, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Tooltip = {'restore values to default values'};
            app.DefaultButton.Text = 'Default';
            app.RequiredPanel = uipanel(app.Config);
            app.RequiredPanel.Title = 'Required';
            app.create_psthDropDownLabel = uilabel(app.RequiredPanel);
            app.create_psthDropDownLabel.Text = 'create_psth';
            app.create_psthDropDown = uidropdown(app.RequiredPanel);
            app.create_psthDropDown.Items = {'true', 'false'};
            app.create_psthDropDown.ValueChangedFcn = createCallbackFcn(app, @create_psthDropDownValueChanged, true);
            app.create_psthDropDown.Tooltip = {'Run mutual info analysis'};
            app.create_psthDropDown.Value = 'true';
            app.create_mntsDropDownLabel = uilabel(app.RequiredPanel);
            app.create_mntsDropDownLabel.Text = 'create_mnts';
            app.create_mntsDropDown = uidropdown(app.RequiredPanel);
            app.create_mntsDropDown.Items = {'true', 'false'};
            app.create_mntsDropDown.ValueChangedFcn = createCallbackFcn(app, @create_mntsDropDownValueChanged, true);
            app.create_mntsDropDown.Tooltip = {'Run mutual info analysis'};
            app.create_mntsDropDown.Value = 'true';
            app.parse_filesDropDownLabel = uilabel(app.RequiredPanel);
            app.parse_filesDropDownLabel.Text = 'parse_files';
            app.parse_filesDropDown = uidropdown(app.RequiredPanel);
            app.parse_filesDropDown.Items = {'true', 'false'};
            app.parse_filesDropDown.ValueChangedFcn = createCallbackFcn(app, @parse_filesDropDownValueChanged, true);
            app.parse_filesDropDown.Tooltip = {'Controls if .plx/.pl2 files are parsed'};
            app.parse_filesDropDown.Value = 'true';
            app.label_channelsDropDownLabel = uilabel(app.RequiredPanel);
            app.label_channelsDropDownLabel.Text = 'label_channels';
            app.label_channelsDropDown = uidropdown(app.RequiredPanel);
            app.label_channelsDropDown.Items = {'true', 'false'};
            app.label_channelsDropDown.Tooltip = {'Label neurons according to labels csv'};
            app.label_channelsDropDown.Value = 'true';
            app.rf_analysisDropDownLabel = uilabel(app.RequiredPanel);
            app.rf_analysisDropDownLabel.Text = 'rf_analysis';
            app.rf_analysisDropDown = uidropdown(app.RequiredPanel);
            app.rf_analysisDropDown.Items = {'true', 'false'};
            app.rf_analysisDropDown.ValueChangedFcn = createCallbackFcn(app, @rf_analysisDropDownValueChanged, true);
            app.rf_analysisDropDown.Tooltip = {'Run receptive field analysis'};
            app.rf_analysisDropDown.Value = 'true';
            app.make_psth_graphsDropDownLabel = uilabel(app.RequiredPanel);
            app.make_psth_graphsDropDownLabel.Text = 'make_psth_graphs';
            app.make_psth_graphsDropDown = uidropdown(app.RequiredPanel);
            app.make_psth_graphsDropDown.Items = {'true', 'false'};
            app.make_psth_graphsDropDown.ValueChangedFcn = createCallbackFcn(app, @make_psth_graphsDropDownValueChanged, true);
            app.make_psth_graphsDropDown.Tooltip = {'Create PSTH graphs'};
            app.make_psth_graphsDropDown.Value = 'true';
            app.nv_analysisDropDownLabel = uilabel(app.RequiredPanel);
            app.nv_analysisDropDownLabel.Text = 'nv_analysis';
            app.nv_analysisDropDown = uidropdown(app.RequiredPanel);
            app.nv_analysisDropDown.Items = {'true', 'false'};
            app.nv_analysisDropDown.ValueChangedFcn = createCallbackFcn(app, @nv_analysisDropDownValueChanged, true);
            app.nv_analysisDropDown.Tooltip = {'Run normalized variance analysis'};
            app.nv_analysisDropDown.Value = 'true';
            app.info_analysisDropDownLabel = uilabel(app.RequiredPanel);
            app.info_analysisDropDownLabel.Text = 'info_analysis';
            app.info_analysisDropDown = uidropdown(app.RequiredPanel);
            app.info_analysisDropDown.Items = {'true', 'false'};
            app.info_analysisDropDown.Tooltip = {'Run mutual info analysis'};
            app.info_analysisDropDown.Value = 'true';
            app.psth_classifyDropDownLabel = uilabel(app.RequiredPanel);
            app.psth_classifyDropDownLabel.Text = 'psth_classify';
            app.psth_classifyDropDown = uidropdown(app.RequiredPanel);
            app.psth_classifyDropDown.Items = {'true', 'false'};
            app.psth_classifyDropDown.ValueChangedFcn = createCallbackFcn(app, @psth_classifyDropDownValueChanged, true);
            app.psth_classifyDropDown.Tooltip = {'Run psth classification'};
            app.psth_classifyDropDown.Value = 'true';
            app.pc_analysisDropDownLabel = uilabel(app.RequiredPanel);
            app.pc_analysisDropDownLabel.Text = 'pc_analysis';
            app.pc_analysisDropDown = uidropdown(app.RequiredPanel);
            app.pc_analysisDropDown.Items = {'true', 'false'};
            app.pc_analysisDropDown.ValueChangedFcn = createCallbackFcn(app, @pc_analysisDropDownValueChanged, true);
            app.pc_analysisDropDown.Tooltip = {'Run mutual info analysis'};
            app.pc_analysisDropDown.Value = 'true';
            app.ic_analysisDropDownLabel = uilabel(app.RequiredPanel);
            app.ic_analysisDropDownLabel.Text = 'ic_analysis';
            app.ic_analysisDropDown = uidropdown(app.RequiredPanel);
            app.ic_analysisDropDown.Items = {'true', 'false'};
            app.ic_analysisDropDown.ValueChangedFcn = createCallbackFcn(app, @ic_analysisDropDownValueChanged, true);
            app.ic_analysisDropDown.Tooltip = {'Run psth classification'};
            app.ic_analysisDropDown.Value = 'true';
            app.bin_sizeEditFieldLabel = uilabel(app.RequiredPanel);
            app.bin_sizeEditFieldLabel.Text = 'bin_size';
            app.bin_sizeEditField = uieditfield(app.RequiredPanel, 'numeric');
            app.bin_sizeEditField.Tooltip = {'Bin size to make histogram'};
            app.bin_sizeEditField.Value = 0.005;
            app.pre_timeEditFieldLabel = uilabel(app.RequiredPanel);
            app.pre_timeEditFieldLabel.Text = 'pre_time';
            app.pre_timeEditField = uieditfield(app.RequiredPanel, 'numeric');
            app.pre_timeEditField.Tooltip = {'Pre time window before event onset'};
            app.pre_timeEditField.Value = 0.1;
            app.post_timeEditFieldLabel = uilabel(app.RequiredPanel);
            app.post_timeEditFieldLabel.Text = 'post_time';
            app.post_timeEditField = uieditfield(app.RequiredPanel, 'numeric');
            app.post_timeEditField.Tooltip = {'Post time window after event onset'};
            app.post_timeEditField.Value = 0.2;
            app.pre_startEditFieldLabel = uilabel(app.RequiredPanel);
            app.pre_startEditFieldLabel.Text = 'pre_start';
            app.pre_startEditField = uieditfield(app.RequiredPanel, 'numeric');
            app.pre_startEditField.Tooltip = {'Pre time window before event onset'};
            app.pre_endEditFieldLabel = uilabel(app.RequiredPanel);
            app.pre_endEditFieldLabel.Text = 'pre_end';
            app.pre_endEditField = uieditfield(app.RequiredPanel, 'numeric');
            app.pre_endEditField.Tooltip = {'Post time window after event onset'};
            app.post_startEditFieldLabel = uilabel(app.RequiredPanel);
            app.post_startEditFieldLabel.Text = 'post_start';
            app.post_startEditField = uieditfield(app.RequiredPanel, 'numeric');
            app.post_startEditField.Tooltip = {'Pre time window before event onset'};
            app.post_endEditFieldLabel = uilabel(app.RequiredPanel);
            app.post_endEditFieldLabel.Text = 'post_end';
            app.post_endEditField = uieditfield(app.RequiredPanel, 'numeric');
            app.post_endEditField.Tooltip = {'Post time window after event onset'};
            app.ParserPanel = uipanel(app.Config);
            app.ParserPanel.Title = 'Parser';
            app.total_trialsEditFieldLabel = uilabel(app.ParserPanel);
            app.total_trialsEditFieldLabel.Text = 'total_trials';
            app.total_trialsEditField = uieditfield(app.ParserPanel, 'numeric');
            app.total_trialsEditField.Tooltip = {'Total trials for each event'};
            app.total_trialsEditField.Value = 100;
            app.trial_lower_boundEditFieldLabel = uilabel(app.ParserPanel);
            app.trial_lower_boundEditFieldLabel.Text = 'trial_lower_bound';
            app.trial_lower_boundEditField = uieditfield(app.ParserPanel, 'numeric');
            app.trial_lower_boundEditField.ValueChangedFcn = createCallbackFcn(app, @trial_lower_boundEditFieldValueChanged, true);
            app.trial_lower_boundEditField.Tooltip = {'Lower limit of expected trials for each event'};
            app.trial_lower_boundEditField.Value = 50;
            app.total_eventsEditFieldLabel = uilabel(app.ParserPanel);
            app.total_eventsEditFieldLabel.Text = 'total_events';
            app.total_eventsEditField = uieditfield(app.ParserPanel, 'numeric');
            app.total_eventsEditField.Tooltip = {'How many events are there'};
            app.total_eventsEditField.Value = 4;
            app.is_non_strobed_and_strobedDropDown = uidropdown(app.ParserPanel);
            app.is_non_strobed_and_strobedDropDown.Items = {'true', 'false'};
            app.is_non_strobed_and_strobedDropDown.Tooltip = {'Recordings is a mix of strobed and non strobed'};
            app.is_non_strobed_and_strobedDropDown.Value = 'true';
            app.is_non_strobed_and_strobedDropDownLabel = uilabel(app.ParserPanel);
            app.is_non_strobed_and_strobedDropDownLabel.Text = 'is_non_strobed_and_strobed';
            app.event_mapEditFieldLabel = uilabel(app.ParserPanel);
            app.event_mapEditFieldLabel.Text = 'event_map';
            app.event_mapEditField = uieditfield(app.ParserPanel, 'text');
            app.event_mapEditField.Tooltip = {'Maps strobed to non strobed. Can be left empty.'; ''; 'ex. [1 2 3 6]'};
            app.PSTHorMNTSPanel = uipanel(app.Config);
            app.PSTHorMNTSPanel.Title = 'PSTH or MNTS';
            app.trial_rangeEditFieldLabel = uilabel(app.PSTHorMNTSPanel);
            app.trial_rangeEditFieldLabel.Text = 'trial_range';
            app.trial_rangeEditField = uieditfield(app.PSTHorMNTSPanel, 'text');
            app.trial_rangeEditField.Tooltip = {'Inclusive range for trials. When empty'; ' takes all trials.'; ''; 'ex. [1 300]'};
            app.wanted_eventsEditFieldLabel = uilabel(app.PSTHorMNTSPanel);
            app.wanted_eventsEditFieldLabel.Text = 'wanted_events';
            app.wanted_eventsEditField = uieditfield(app.PSTHorMNTSPanel, 'text');
            app.wanted_eventsEditField.Tooltip = {'Controls which events are used. If empty'; ' uses all events.'; ''; 'ex. [1 3 4]'};
            app.convert_mnts_psthDropDownLabel = uilabel(app.PSTHorMNTSPanel);
            app.convert_mnts_psthDropDownLabel.Text = 'convert_mnts_psth';
            app.convert_mnts_psthDropDown = uidropdown(app.PSTHorMNTSPanel);
            app.convert_mnts_psthDropDown.Items = {'true', 'false'};
            app.convert_mnts_psthDropDown.Tooltip = {'Run psth classification'};
            app.convert_mnts_psthDropDown.Value = 'true';
            app.update_psth_windowsDropDownLabel = uilabel(app.PSTHorMNTSPanel);
            app.update_psth_windowsDropDownLabel.Text = 'update_psth_windows';
            app.update_psth_windowsDropDown = uidropdown(app.PSTHorMNTSPanel);
            app.update_psth_windowsDropDown.Items = {'true', 'false'};
            app.update_psth_windowsDropDown.Tooltip = {'Run psth classification'};
            app.update_psth_windowsDropDown.Value = 'true';
            app.trial_lower_boundEditField_2Label = uilabel(app.PSTHorMNTSPanel);
            app.trial_lower_boundEditField_2Label.Text = 'trial_lower_bound';
            app.trial_lower_boundEditField_2 = uieditfield(app.PSTHorMNTSPanel, 'numeric');
            app.trial_lower_boundEditField_2.ValueChangedFcn = createCallbackFcn(app, @trial_lower_boundEditField_2ValueChanged, true);
            app.trial_lower_boundEditField_2.Tooltip = {'Lower limit of expected trials for each event'};
            app.trial_lower_boundEditField_2.Value = 50;
            app.ReceptiveFieldAnalysisPanel = uipanel(app.Config);
            app.ReceptiveFieldAnalysisPanel.Title = 'Receptive Field Analysis';
            app.spanEditFieldLabel = uilabel(app.ReceptiveFieldAnalysisPanel);
            app.spanEditFieldLabel.Text = 'span';
            app.spanEditField = uieditfield(app.ReceptiveFieldAnalysisPanel, 'numeric');
            app.spanEditField.Tooltip = {'How many bins smoothed when finding significant response'};
            app.spanEditField.Value = 3;
            app.threshold_scaleEditFieldLabel = uilabel(app.ReceptiveFieldAnalysisPanel);
            app.threshold_scaleEditFieldLabel.Text = 'threshold_scale';
            app.threshold_scaleEditField = uieditfield(app.ReceptiveFieldAnalysisPanel, 'numeric');
            app.threshold_scaleEditField.Tooltip = {'Scales background firing rate standard deviation for threshold'};
            app.threshold_scaleEditField.Value = 1.65;
            app.sig_binsEditFieldLabel = uilabel(app.ReceptiveFieldAnalysisPanel);
            app.sig_binsEditFieldLabel.Text = 'sig_bins';
            app.sig_binsEditField = uieditfield(app.ReceptiveFieldAnalysisPanel, 'numeric');
            app.sig_binsEditField.Tooltip = {'# consecutive bins needed above threshold for significance'};
            app.sig_binsEditField.Value = 3;
            app.sig_checkEditFieldLabel = uilabel(app.ReceptiveFieldAnalysisPanel);
            app.sig_checkEditFieldLabel.Text = 'sig_check';
            app.sig_checkEditField = uieditfield(app.ReceptiveFieldAnalysisPanel, 'numeric');
            app.sig_checkEditField.Tooltip = {'Control statistical check for significant response'};
            app.sig_checkEditField.Value = 1;
            app.PSTHGraphingPanel = uipanel(app.Config);
            app.PSTHGraphingPanel.Title = 'PSTH Graphing';
            app.make_region_subplotDropDownLabel = uilabel(app.PSTHGraphingPanel);
            app.make_region_subplotDropDownLabel.Text = 'make_region_subplot';
            app.make_region_subplotDropDown = uidropdown(app.PSTHGraphingPanel);
            app.make_region_subplotDropDown.Items = {'true', 'false'};
            app.make_region_subplotDropDown.Tooltip = {'Plot all units on single .fig'};
            app.make_region_subplotDropDown.Value = 'true';
            app.sub_columnsEditFieldLabel = uilabel(app.PSTHGraphingPanel);
            app.sub_columnsEditFieldLabel.Text = 'sub_columns';
            app.sub_columnsEditField = uieditfield(app.PSTHGraphingPanel, 'numeric');
            app.sub_columnsEditField.Tooltip = {'How many PSTHs plotted on row.'};
            app.sub_columnsEditField.Value = 50;
            app.sub_rowsEditFieldLabel = uilabel(app.PSTHGraphingPanel);
            app.sub_rowsEditFieldLabel.Text = 'sub_rows';
            app.sub_rowsEditField = uieditfield(app.PSTHGraphingPanel, 'numeric');
            app.sub_rowsEditField.Tooltip = {'How many PSTHs plotted on row.'};
            app.sub_rowsEditField.Value = 50;
            app.NormalizedVarianceAnalysisPanel = uipanel(app.Config);
            app.NormalizedVarianceAnalysisPanel.Title = 'Normalized Variance Analysis';
            app.epsilonEditFieldLabel = uilabel(app.NormalizedVarianceAnalysisPanel);
            app.epsilonEditFieldLabel.Text = 'epsilon';
            app.epsilonEditField = uieditfield(app.NormalizedVarianceAnalysisPanel, 'numeric');
            app.epsilonEditField.Tooltip = {'Constant used to prevent division by zero'};
            app.epsilonEditField.Value = 0.01;
            app.norm_var_scalingEditFieldLabel = uilabel(app.NormalizedVarianceAnalysisPanel);
            app.norm_var_scalingEditFieldLabel.Text = 'norm_var_scaling';
            app.norm_var_scalingEditField = uieditfield(app.NormalizedVarianceAnalysisPanel, 'numeric');
            app.norm_var_scalingEditField.Tooltip = {'Scale the normalized variance'};
            app.norm_var_scalingEditField.Value = 0.2;
            app.separate_eventsDropDownLabel = uilabel(app.NormalizedVarianceAnalysisPanel);
            app.separate_eventsDropDownLabel.Text = 'separate_events';
            app.separate_eventsDropDown = uidropdown(app.NormalizedVarianceAnalysisPanel);
            app.separate_eventsDropDown.Items = {'true', 'false'};
            app.separate_eventsDropDown.Tooltip = {'Separate event trials or combine all trials'};
            app.separate_eventsDropDown.Value = 'true';
            app.PSTHClassifierPanel = uipanel(app.Config);
            app.PSTHClassifierPanel.Title = 'PSTH Classifier';
            app.bootstrap_classifierDropDownLabel = uilabel(app.PSTHClassifierPanel);
            app.bootstrap_classifierDropDownLabel.Text = 'bootstrap_classifier';
            app.bootstrap_classifierDropDown = uidropdown(app.PSTHClassifierPanel);
            app.bootstrap_classifierDropDown.Items = {'true', 'false'};
            app.bootstrap_classifierDropDown.Tooltip = {'Find random info via bootstrapping'};
            app.bootstrap_classifierDropDown.Value = 'true';
            app.boot_iterationsEditFieldLabel = uilabel(app.PSTHClassifierPanel);
            app.boot_iterationsEditFieldLabel.Text = 'boot_iterations';
            app.boot_iterationsEditField = uieditfield(app.PSTHClassifierPanel, 'numeric');
            app.boot_iterationsEditField.Tooltip = {'How many iterations used to find avg random info'};
            app.boot_iterationsEditField.Value = 50;
            app.PCAPanel = uipanel(app.Config);
            app.PCAPanel.Title = 'PCA';
            app.feature_filterDropDownLabel = uilabel(app.PCAPanel);
            app.feature_filterDropDownLabel.Text = 'feature_filter';
            app.feature_filterDropDown = uidropdown(app.PCAPanel);
            app.feature_filterDropDown.Items = {'all', 'percent_var', 'pcs'};
            app.feature_filterDropDown.Tooltip = {'''all'': uses all pcs'; '''percent_var'': pc selection based on % variance explained'; '''pcs'': first x components'};
            app.feature_filterDropDown.Value = 'all';
            app.feature_valueEditFieldLabel = uilabel(app.PCAPanel);
            app.feature_valueEditFieldLabel.Text = 'feature_value';
            app.feature_valueEditField = uieditfield(app.PCAPanel, 'numeric');
            app.feature_valueEditField.Tooltip = {'''all'': 0'; '''percent_var'': 1-100%'; '''pcs'': # of pcs'};
            app.feature_valueEditField.Value = 4;
            app.ICAPanel = uipanel(app.Config);
            app.ICAPanel.Title = 'ICA';
            app.ic_pcEditFieldLabel = uilabel(app.ICAPanel);
            app.ic_pcEditFieldLabel.Text = 'ic_pc';
            app.ic_pcEditField = uieditfield(app.ICAPanel, 'numeric');
            app.ic_pcEditField.Tooltip = {'# pcs used for ICA'; 'default = 0'};
            app.ic_pcEditField.Value = 3;
            app.extendedEditFieldLabel = uilabel(app.ICAPanel);
            app.extendedEditFieldLabel.Text = 'extended';
            app.extendedEditField = uieditfield(app.ICAPanel, 'numeric');
            app.extendedEditField.Tooltip = {'# blocks for extended-ica'};
            app.extendedEditField.Value = 3;
            app.spheringDropDownLabel = uilabel(app.ICAPanel);
            app.spheringDropDownLabel.Text = 'sphering';
            app.spheringDropDown = uidropdown(app.ICAPanel);
            app.spheringDropDown.Items = {'on', 'off'};
            app.spheringDropDown.Tooltip = {'''on'': spheres data'; '''off'': doesnt sphere data'; 'default = ''on'''};
            app.spheringDropDown.Value = 'on';
            app.annealEditFieldLabel = uilabel(app.ICAPanel);
            app.annealEditFieldLabel.Text = 'anneal';
            app.annealEditField = uieditfield(app.ICAPanel, 'text');
            app.annealEditField.Tooltip = {'controls speed of convergence. Can set anneal to default for defaults. (0'; ' 1]'; 'default: .9'; 'if extended: .98'};
            app.anneal_degEditFieldLabel = uilabel(app.ICAPanel);
            app.anneal_degEditFieldLabel.Text = 'anneal_deg';
            app.anneal_degEditField = uieditfield(app.ICAPanel, 'numeric');
            app.anneal_degEditField.Tooltip = {'Weight change for anneal'; 'default = 70'};
            app.anneal_degEditField.Value = 3;
            app.stopEditFieldLabel = uilabel(app.ICAPanel);
            app.stopEditFieldLabel.Text = 'stop';
            app.stopEditField = uieditfield(app.ICAPanel, 'text');
            app.stopEditField.Tooltip = {'Stops training when weight change is less than stop. Can set stop to default for default action.'; 'default = 1e-6'; 'tot channels < 33'; 'default = 1e-7'};
            app.max_stepsEditFieldLabel = uilabel(app.ICAPanel);
            app.max_stepsEditFieldLabel.Text = 'max_steps';
            app.max_stepsEditField = uieditfield(app.ICAPanel, 'numeric');
            app.max_stepsEditField.Tooltip = {'Max training steps'; 'default = 512'};
            app.max_stepsEditField.Value = 3;
            app.biasDropDownLabel = uilabel(app.ICAPanel);
            app.biasDropDownLabel.Text = 'bias';
            app.biasDropDown = uidropdown(app.ICAPanel);
            app.biasDropDown.Items = {'on', 'off'};
            app.biasDropDown.Tooltip = {'bias adjustment'; 'default = ''on'''};
            app.biasDropDown.Value = 'on';
            app.momentumEditFieldLabel = uilabel(app.ICAPanel);
            app.momentumEditFieldLabel.Text = 'momentum';
            app.momentumEditField = uieditfield(app.ICAPanel, 'numeric');
            app.momentumEditField.Tooltip = {'Training momentum [0<#<1]'; 'default = 0'};
            app.momentumEditField.Value = 3;
            app.rnd_resetDropDown = uidropdown(app.ICAPanel);
            app.rnd_resetDropDown.Items = {'true', 'false'};
            app.rnd_resetDropDown.Tooltip = {'Control if random seed is reset to return same decmposition.'; 'default = false'};
            app.rnd_resetDropDown.Value = 'true';
            app.rnd_resetDropDownLabel = uilabel(app.ICAPanel);
            app.rnd_resetDropDownLabel.Text = 'rnd_reset';
            app.verboseEditFieldLabel = uilabel(app.ICAPanel);
            app.verboseEditFieldLabel.Text = 'verbose';
            app.verboseEditField = uieditfield(app.ICAPanel, 'text');
            app.verboseEditField.Tooltip = {'Give detailed messages in terminal.'; 'default = ''off'''};
            app.FormatDropDownLabel = uilabel(app.Config);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.Config);
            app.FormatDropDown.Items = {'PSTH', 'MNTS'};
            app.FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @FormatDropDownValueChanged, true);
            app.FormatDropDown.Value = 'PSTH';
            app.UploadButton = uibutton(app.Config, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Tooltip = {'upload an existing config file to load in parameters'};
            app.UploadButton.Text = 'Upload';
            % Show the figure after all components are created
            app.Config.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigCSVGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Config)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Config)
        end
    end
end